classdef app_regressao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Results                    matlab.ui.container.Panel
        ResultsTextArea            matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
        SuperioresEditField        matlab.ui.control.EditField
        SuperioresEditFieldLabel   matlab.ui.control.Label
        InferioresEditField        matlab.ui.control.EditField
        InferioresEditFieldLabel   matlab.ui.control.Label
        ICEditField                matlab.ui.control.EditField
        ValoresdeX1EditField       matlab.ui.control.EditField
        ValoresdeX1EditFieldLabel  matlab.ui.control.Label
        ValoresdeX2EditField       matlab.ui.control.EditField
        ValoresdeX2EditFieldLabel  matlab.ui.control.Label
        ValoresdeYEditField        matlab.ui.control.EditField
        ValoresdeYEditFieldLabel   matlab.ui.control.Label
        CriarModeloButton          matlab.ui.control.Button
        CondiesiniciaisLabel       matlab.ui.control.Label
        LimitesLabel               matlab.ui.control.Label
        grafico                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function plotModelSurface(app, X1, X2, coef_details, lower_bounds, upper_bounds, optimal_point, optimal_Y)
        % Cria uma grade de pontos usando os limites inferiores e superiores
        [X1Grid, X2Grid] = meshgrid(linspace(lower_bounds(1), upper_bounds(1), 100), ...
                                    linspace(lower_bounds(2), upper_bounds(2), 100));

        % Avalia a função do modelo nos pontos da grade
        YGrid = coef_details(1) + coef_details(2)*X1Grid + coef_details(3)*X2Grid + ...
                coef_details(4)*X1Grid.^2 + coef_details(5)*X2Grid.^2 + coef_details(6)*X1Grid.*X2Grid;

        % Plotagem da superfície no componente UIAxes
        surfaceObject = surf(app.grafico, X1Grid, X2Grid, YGrid);
        xlabel(app.grafico, 'X1');
        ylabel(app.grafico, 'X2');
        zlabel(app.grafico, 'Y');
        title(app.grafico, 'Superfície de resposta gerada pelo modelo');
        colormap(app.grafico, 'hsv');
        %surfaceObject.EdgeColor = 'none';
        hold(app.grafico, 'on'); % Mantém o gráfico atual para adicionar mais elementos gráficos
        plot3(app.grafico, optimal_point(1), optimal_point(2), optimal_Y, 'b.', 'MarkerSize', 30, 'LineWidth', 6);
        hold(app.grafico, 'off'); % Desliga o modo hold
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriarModeloButton
        function CriarModeloButtonPushed2(app, event)
            X1 = str2double(strsplit(app.ValoresdeX1EditField.Value, ','));
            X2 = str2double(strsplit(app.ValoresdeX2EditField.Value, ','));
            Y = str2double(strsplit(app.ValoresdeYEditField.Value, ','));

            % Verifica se X1, X2 e Y são colunas, senão, transforma em colunas
            if size(X1, 2) > 1
                X1 = X1';
            end
            if size(X2, 2) > 1
                X2 = X2';
            end
            if size(Y, 2) > 1
                Y = Y';
            end
            lower_bounds = str2double(strsplit(app.InferioresEditField.Value, ','));
            upper_bounds = str2double(strsplit(app.SuperioresEditField.Value, ','));


            % Verifica se os limites são NaN
            if any(isnan(lower_bounds)) || any(isnan(upper_bounds))
                uialert(app.UIFigure, 'Verifique os limites inseridos. Os limites devem ser números válidos.', 'Erro nos Limites');
                return;  % Sai do callback se houver algum NaN
            end
            
            if isempty(app.ICEditField.Value)
                IC = [];
            else
                IC = str2double(strsplit(app.ICEditField.Value, ','));
            end
            
            % Gera Modelo
            [optimal_point, optimal_Y, coef_details, R2_vals, F_test] = gerarModelo(X1, X2, Y, IC, lower_bounds, upper_bounds);
            
            % Mostra informações do modelo
            resultsText = sprintf('Ponto ótimo: (%.2f, %.2f)\nY ótimo: %.2f\nCoeficientes: %s\nR2: %.3f, R2 ajustado: %.3f\nValor F: %.3f, Valor p: %.3g', ...
            optimal_point(1), optimal_point(2), optimal_Y, mat2str(coef_details(:,1)), R2_vals(1), R2_vals(2), F_test(1), F_test(2));
            app.ResultsTextArea.Value = resultsText;
            app.Results.Visible = 'on';

            % Plota superfície
            plotModelSurface(app, X1, X2, coef_details(:,1), lower_bounds, upper_bounds, optimal_point, optimal_Y);
    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico = uiaxes(app.UIFigure);
            title(app.grafico, 'Title')
            xlabel(app.grafico, 'X')
            ylabel(app.grafico, 'Y')
            zlabel(app.grafico, 'Z')
            app.LimitesLabel = uilabel(app.UIFigure);
            app.LimitesLabel.Text = 'Limites:';
            app.CondiesiniciaisLabel = uilabel(app.UIFigure);
            app.CondiesiniciaisLabel.Text = 'Condições iniciais:';
            app.CriarModeloButton = uibutton(app.UIFigure, 'push');
            app.CriarModeloButton.ButtonPushedFcn = createCallbackFcn(app, @CriarModeloButtonPushed2, true);
            app.CriarModeloButton.Text = 'Criar Modelo';
            app.ValoresdeYEditFieldLabel = uilabel(app.UIFigure);
            app.ValoresdeYEditFieldLabel.Text = 'Valores de Y';
            app.ValoresdeYEditField = uieditfield(app.UIFigure, 'text');
            app.ValoresdeX2EditFieldLabel = uilabel(app.UIFigure);
            app.ValoresdeX2EditFieldLabel.Text = 'Valores de X2';
            app.ValoresdeX2EditField = uieditfield(app.UIFigure, 'text');
            app.ValoresdeX1EditFieldLabel = uilabel(app.UIFigure);
            app.ValoresdeX1EditFieldLabel.Text = 'Valores de X1';
            app.ValoresdeX1EditField = uieditfield(app.UIFigure, 'text');
            app.ICEditField = uieditfield(app.UIFigure, 'text');
            app.InferioresEditFieldLabel = uilabel(app.UIFigure);
            app.InferioresEditFieldLabel.Text = 'Inferiores';
            app.InferioresEditField = uieditfield(app.UIFigure, 'text');
            app.SuperioresEditFieldLabel = uilabel(app.UIFigure);
            app.SuperioresEditFieldLabel.Text = 'Superiores';
            app.SuperioresEditField = uieditfield(app.UIFigure, 'text');
            app.Results = uipanel(app.UIFigure);
            app.Results.Visible = 'off';
            app.Label = uilabel(app.Results);
            app.Label.Text = '';
            app.ResultsTextArea = uitextarea(app.Results);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_regressao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end